classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        TabGroup                   matlab.ui.container.TabGroup
        NolinealesTab              matlab.ui.container.Tab
        GridLayout2                matlab.ui.container.GridLayout
        GridLayout4                matlab.ui.container.GridLayout
        FuncinEditField            matlab.ui.control.EditField
        FuncinEditFieldLabel       matlab.ui.control.Label
        EscojaelmtododebusquedalinealButtonGroup  matlab.ui.container.ButtonGroup
        DespejeButton              matlab.ui.control.RadioButton
        WolfeButton                matlab.ui.control.RadioButton
        BiseccinButton             matlab.ui.control.RadioButton
        CalcularButton             matlab.ui.control.Button
        EscojaelalgoritmoautilizarButtonGroup  matlab.ui.container.ButtonGroup
        QuasinewtonButton          matlab.ui.control.RadioButton
        NewtonButton               matlab.ui.control.RadioButton
        CauchyGradienteDescendenteButton  matlab.ui.control.RadioButton
        GridLayout3                matlab.ui.container.GridLayout
        IteracionesEditField       matlab.ui.control.NumericEditField
        IteracionesEditFieldLabel  matlab.ui.control.Label
        YEditField                 matlab.ui.control.NumericEditField
        YEditFieldLabel            matlab.ui.control.Label
        XEditField                 matlab.ui.control.NumericEditField
        XEditFieldLabel            matlab.ui.control.Label
        LinealesTab                matlab.ui.container.Tab
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            fx = app.FuncinEditField.Value;
            initial = [app.XEditField.Value;app.YEditField.Value];
            tolerance = 0.00000000001;
            maxIter = app.IteracionesEditField.Value;
            busquedaLineal = app.EscojaelmtododebusquedalinealButtonGroup.SelectedObject.Text;
            if app.CauchyGradienteDescendenteButton.Value == 1
                cauchy(fx, initial, tolerance, maxIter, busquedaLineal)
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.NolinealesTab = uitab(app.TabGroup);
            app.NolinealesTab.Title = 'No lineales';
            app.GridLayout2 = uigridlayout(app.NolinealesTab);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.XEditFieldLabel = uilabel(app.GridLayout3);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.GridLayout3, 'numeric');
            app.YEditFieldLabel = uilabel(app.GridLayout3);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.GridLayout3, 'numeric');
            app.IteracionesEditFieldLabel = uilabel(app.GridLayout3);
            app.IteracionesEditFieldLabel.Text = 'Iteraciones';
            app.IteracionesEditField = uieditfield(app.GridLayout3, 'numeric');
            app.EscojaelalgoritmoautilizarButtonGroup = uibuttongroup(app.GridLayout2);
            app.EscojaelalgoritmoautilizarButtonGroup.Title = 'Escoja el algoritmo a utilizar';
            app.CauchyGradienteDescendenteButton = uiradiobutton(app.EscojaelalgoritmoautilizarButtonGroup);
            app.CauchyGradienteDescendenteButton.Text = 'Cauchy - Gradiente Descendente ';
            app.CauchyGradienteDescendenteButton.Value = true;
            app.NewtonButton = uiradiobutton(app.EscojaelalgoritmoautilizarButtonGroup);
            app.NewtonButton.Text = 'Newton';
            app.QuasinewtonButton = uiradiobutton(app.EscojaelalgoritmoautilizarButtonGroup);
            app.QuasinewtonButton.Text = 'Quasi newton';
            app.CalcularButton = uibutton(app.GridLayout2, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.EscojaelmtododebusquedalinealButtonGroup = uibuttongroup(app.GridLayout2);
            app.EscojaelmtododebusquedalinealButtonGroup.Title = 'Escoja el método de busqueda lineal';
            app.BiseccinButton = uiradiobutton(app.EscojaelmtododebusquedalinealButtonGroup);
            app.BiseccinButton.Text = 'Bisección';
            app.BiseccinButton.Value = true;
            app.WolfeButton = uiradiobutton(app.EscojaelmtododebusquedalinealButtonGroup);
            app.WolfeButton.Text = 'Wolfe';
            app.DespejeButton = uiradiobutton(app.EscojaelmtododebusquedalinealButtonGroup);
            app.DespejeButton.Text = 'Despeje';
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.FuncinEditFieldLabel = uilabel(app.GridLayout4);
            app.FuncinEditFieldLabel.Text = 'Función';
            app.FuncinEditField = uieditfield(app.GridLayout4, 'text');
            app.LinealesTab = uitab(app.TabGroup);
            app.LinealesTab.Title = 'Lineales';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end